classdef lotkaVolterra < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Label_u_psi           matlab.ui.control.Label
        Slider_h              matlab.ui.control.Slider
        Label_11              matlab.ui.control.Label
        EditField_T           matlab.ui.control.NumericEditField
        TEditFieldLabel       matlab.ui.control.Label
        EditField_p           matlab.ui.control.NumericEditField
        Label_9               matlab.ui.control.Label
        EditField_alpha2      matlab.ui.control.NumericEditField
        Label_7               matlab.ui.control.Label
        EditField_beta2       matlab.ui.control.NumericEditField
        Label_8               matlab.ui.control.Label
        EditField_beta1       matlab.ui.control.NumericEditField
        Label_6               matlab.ui.control.Label
        EditField_x0Predator  matlab.ui.control.NumericEditField
        Label_3               matlab.ui.control.Label
        EditField_x0Prey      matlab.ui.control.NumericEditField
        Label_2               matlab.ui.control.Label
        EditField_timeEnd     matlab.ui.control.NumericEditField
        Label_5               matlab.ui.control.Label
        EditField_time0       matlab.ui.control.NumericEditField
        Label_4               matlab.ui.control.Label
        Label_System          matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %time range
        time1 = 0;
        time2 = 100;
        h = 0.01;%step(Euler's method)
        x0 = [300 200];%initial number of animals
        %coefficients
        beta1 = 0.41;
        alpha2 = 200;
        beta2 = 1;
        p = 1;
        T = 0.01;
    end
    
    methods (Access = private)
        
        
        function solutionAndPlotting(app)
            %ode system
            %ode45
            [t,x] = ode45(@(t,x)funSys(t, x, app.beta1, app.alpha2, app.beta2, app.p, app.T), [app.time1 app.time2], app.x0);
            %Euler's method
            x2(:,1) = app.x0(:);
            n = (app.time2-app.time1)/app.h;%number of segments
            time = app.time1:app.h:app.time2;%time range
            for i = 1:n
                x2(:,i+1) = x2(:, i) + app.h*funSys(time(i), x2(:, i), app.beta1, app.alpha2, app.beta2, app.p, app.T);
            end
            %plots
            plot(app.UIAxes,t, x(:,1), 'b', t, x(:,2), 'r');
            legend(app.UIAxes,'Жертвы','Хищники');
            plot(app.UIAxes2,time, x2(1,:), 'b', time, x2(2,:), 'r');
            legend(app.UIAxes2,'Жертвы','Хищники');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditField_time0.Value = app.time1;
            app.EditField_timeEnd.Value = app.time2;
            app.EditField_x0Prey.Value = app.x0(1);
            app.EditField_x0Predator.Value = app.x0(2);
            app.EditField_beta1.Value = app.beta1;
            app.EditField_alpha2.Value = app.alpha2;
            app.EditField_beta2.Value = app.beta2;
            app.EditField_p.Value = app.p;
            app.EditField_T.Value = app.T;
            app.Slider_h.Value = app.h;
            solutionAndPlotting(app);
        end

        % Value changed function: EditField_time0
        function EditField_time0ValueChanged(app, event)
            app.time1 = app.EditField_time0.Value;
            solutionAndPlotting(app);
        end

        % Value changed function: EditField_timeEnd
        function EditField_timeEndValueChanged(app, event)
            app.time2 = app.EditField_timeEnd.Value;
            solutionAndPlotting(app);
        end

        % Value changed function: EditField_x0Prey
        function EditField_x0PreyValueChanged(app, event)
            app.x0(1) = app.EditField_x0Prey.Value;
            solutionAndPlotting(app);
        end

        % Value changed function: EditField_x0Predator
        function EditField_x0PredatorValueChanged(app, event)
            app.x0(2) = app.EditField_x0Predator.Value;
            solutionAndPlotting(app);
        end

        % Value changed function: EditField_beta1
        function EditField_beta1ValueChanged(app, event)
            app.beta1 = app.EditField_beta1.Value;
            solutionAndPlotting(app);
        end

        % Value changed function: EditField_beta2
        function EditField_beta2ValueChanged(app, event)
            app.beta2 = app.EditField_beta2.Value;
            solutionAndPlotting(app);
        end

        % Value changed function: EditField_alpha2
        function EditField_alpha2ValueChanged(app, event)
            app.alpha2 = app.EditField_alpha2.Value;
            solutionAndPlotting(app);
        end

        % Value changed function: EditField_p
        function EditField_pValueChanged(app, event)
            app.p = app.EditField_p.Value;
            solutionAndPlotting(app);
        end

        % Value changed function: EditField_T
        function EditField_TValueChanged(app, event)
            app.T = app.EditField_T.Value;
            solutionAndPlotting(app);
        end

        % Value changing function: Slider_h
        function Slider_hValueChanging(app, event)
            app.h = event.Value;
            solutionAndPlotting(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ode45')
            xlabel(app.UIAxes, 'Время')
            ylabel(app.UIAxes, 'Популяция')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Метод Эйлера')
            xlabel(app.UIAxes2, 'Время')
            ylabel(app.UIAxes2, 'Популяция')
            zlabel(app.UIAxes2, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Модель "Хищник-жертва"';
            app.Label_System = uilabel(app.UIFigure);
            app.Label_System.Interpreter = 'latex';
            app.Label_System.Text = '$\begin{cases}\frac{dx_1}{dt}=f_1 (x_1 (t),x_2 (t))=u(t) x_1-β_1 x_1 x_2 \\ \frac{dx_2}{dt}=f_2 (x_1 (t),x_2 (t))=-α_2 x_2+β_2 x_1 x_2 \end{cases}$';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Нач. время';
            app.EditField_time0 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_time0.ValueChangedFcn = createCallbackFcn(app, @EditField_time0ValueChanged, true);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Кон. время';
            app.EditField_timeEnd = uieditfield(app.UIFigure, 'numeric');
            app.EditField_timeEnd.ValueChangedFcn = createCallbackFcn(app, @EditField_timeEndValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Кол-во жертв';
            app.EditField_x0Prey = uieditfield(app.UIFigure, 'numeric');
            app.EditField_x0Prey.ValueChangedFcn = createCallbackFcn(app, @EditField_x0PreyValueChanged, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Кол-во хищников';
            app.EditField_x0Predator = uieditfield(app.UIFigure, 'numeric');
            app.EditField_x0Predator.ValueChangedFcn = createCallbackFcn(app, @EditField_x0PredatorValueChanged, true);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Interpreter = 'latex';
            app.Label_6.Text = '$β_1$';
            app.EditField_beta1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_beta1.ValueChangedFcn = createCallbackFcn(app, @EditField_beta1ValueChanged, true);
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Interpreter = 'latex';
            app.Label_8.Text = '$β_2$';
            app.EditField_beta2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_beta2.ValueChangedFcn = createCallbackFcn(app, @EditField_beta2ValueChanged, true);
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Interpreter = 'latex';
            app.Label_7.Text = '$α_2$';
            app.EditField_alpha2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_alpha2.ValueChangedFcn = createCallbackFcn(app, @EditField_alpha2ValueChanged, true);
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Interpreter = 'latex';
            app.Label_9.Text = '$ρ$';
            app.EditField_p = uieditfield(app.UIFigure, 'numeric');
            app.EditField_p.ValueChangedFcn = createCallbackFcn(app, @EditField_pValueChanged, true);
            app.TEditFieldLabel = uilabel(app.UIFigure);
            app.TEditFieldLabel.Interpreter = 'latex';
            app.TEditFieldLabel.Text = '$T$';
            app.EditField_T = uieditfield(app.UIFigure, 'numeric');
            app.EditField_T.ValueChangedFcn = createCallbackFcn(app, @EditField_TValueChanged, true);
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = 'Шаг';
            app.Slider_h = uislider(app.UIFigure);
            app.Slider_h.Limits = [0.001 1];
            app.Slider_h.MajorTicks = [0.001 0.2 0.4 0.6 0.8 1];
            app.Slider_h.ValueChangingFcn = createCallbackFcn(app, @Slider_hValueChanging, true);
            app.Slider_h.MinorTicks = [0.001 0.01 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Slider_h.Value = 0.01;
            app.Label_u_psi = uilabel(app.UIFigure);
            app.Label_u_psi.Interpreter = 'latex';
            app.Label_u_psi.Text = '$u= β_1 x_2+pf_2 (t) x_1^{-1}-ψ(Tx_1)^{-1}, ψ(t)= x_1 (t)-px_2 (t)$';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lotkaVolterra

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end